classdef Detection_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NostimulusparametersPanel       matlab.ui.container.Panel
        NostimulusweightEditField       matlab.ui.control.NumericEditField
        NostimulusweightEditFieldLabel  matlab.ui.control.Label
        LightparametersPanel            matlab.ui.container.Panel
        LightStimWeight_5               matlab.ui.control.NumericEditField
        LightStimWeight_4               matlab.ui.control.NumericEditField
        LightStimWeight_3               matlab.ui.control.NumericEditField
        LightStimWeight_2               matlab.ui.control.NumericEditField
        LightStimWeight_1               matlab.ui.control.NumericEditField
        LightweightEditFieldLabel       matlab.ui.control.Label
        LightStimPower_5                matlab.ui.control.NumericEditField
        LightStimPower_4                matlab.ui.control.NumericEditField
        LightStimPower_3                matlab.ui.control.NumericEditField
        LightStimPower_2                matlab.ui.control.NumericEditField
        LightStimPower_1                matlab.ui.control.NumericEditField
        PowermWEditFieldLabel           matlab.ui.control.Label
        LightStimAmpRange               matlab.ui.control.CheckBox
        LightStimAmp_5                  matlab.ui.control.NumericEditField
        LightStimAmp_4                  matlab.ui.control.NumericEditField
        LightStimAmp_3                  matlab.ui.control.NumericEditField
        LightStimAmp_2                  matlab.ui.control.NumericEditField
        LightStimAmp_1                  matlab.ui.control.NumericEditField
        AmplitudeVEditField_2Label      matlab.ui.control.Label
        LightStimDuration               matlab.ui.control.NumericEditField
        DurationmsEditField_3Label      matlab.ui.control.Label
        AuditoryparametersPanel         matlab.ui.container.Panel
        ContextSoundON                  matlab.ui.control.CheckBox
        BackgroundNoiseDirectory        matlab.ui.control.EditField
        BackgroundnoisedirectoryEditFieldLabel  matlab.ui.control.Label
        AudStimWeight                   matlab.ui.control.NumericEditField
        AudweightEditFieldLabel         matlab.ui.control.Label
        AudStimFrequency                matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        AudStimAmplitude                matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel_2      matlab.ui.control.Label
        AudStimDuration                 matlab.ui.control.NumericEditField
        DurationmsEditField_2Label      matlab.ui.control.Label
        WhiskerparametersPanel          matlab.ui.container.Panel
        WhStimWeight_5                  matlab.ui.control.NumericEditField
        WhStimAmp_5mT                   matlab.ui.control.NumericEditField
        WhStimWeight_4                  matlab.ui.control.NumericEditField
        WhStimAmp_4mT                   matlab.ui.control.NumericEditField
        WhStimWeight_3                  matlab.ui.control.NumericEditField
        WhStimAmp_3mT                   matlab.ui.control.NumericEditField
        WhStimWeight_2                  matlab.ui.control.NumericEditField
        WhStimAmp_2mT                   matlab.ui.control.NumericEditField
        WhStimAmp_5                     matlab.ui.control.NumericEditField
        WhStimAmp_4                     matlab.ui.control.NumericEditField
        WhStimAmp_3                     matlab.ui.control.NumericEditField
        WhStimAmp_2                     matlab.ui.control.NumericEditField
        WhStimWeight_1                  matlab.ui.control.NumericEditField
        WhweightEditFieldLabel          matlab.ui.control.Label
        WhStimAmp_1mT                   matlab.ui.control.NumericEditField
        AmplitudemTEditFieldLabel       matlab.ui.control.Label
        WhStimAmp_1                     matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel        matlab.ui.control.Label
        WhStimAmpRange                  matlab.ui.control.CheckBox
        WhStimScalingFactor             matlab.ui.control.NumericEditField
        ScalingfactorEditFieldLabel     matlab.ui.control.Label
        WhStimDuration                  matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel        matlab.ui.control.Label
        SessiontypePanel                matlab.ui.container.Panel
        PdCOCheckBox                    matlab.ui.control.CheckBox
        PharmaCheckBox                  matlab.ui.control.CheckBox
        ChemoCheckBox                   matlab.ui.control.CheckBox
        OptoCheckBox                    matlab.ui.control.CheckBox
        IntracellephysCheckBox          matlab.ui.control.CheckBox
        ExtracellephysCheckBox          matlab.ui.control.CheckBox
        WFCheckBox                      matlab.ui.control.CheckBox
        ThreephotonCheckBox             matlab.ui.control.CheckBox
        TwophotonCheckBox               matlab.ui.control.CheckBox
        ResumeButton                    matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        PassivestimulationPanel         matlab.ui.container.Panel
        PassiveButton                   matlab.ui.control.StateButton
        thenresumeLabel                 matlab.ui.control.Label
        PausepreparethenLabel           matlab.ui.control.Label
        msLabel_12                      matlab.ui.control.Label
        IntertrialintervalEditField     matlab.ui.control.NumericEditField
        IntertrialintervalEditFieldLabel_2  matlab.ui.control.Label
        TrialsEditField                 matlab.ui.control.NumericEditField
        TrialsEditFieldLabel            matlab.ui.control.Label
        PassivestimulationCheckBox      matlab.ui.control.CheckBox
        BehaviourcamerasettingsPanel    matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        StartdelayEditField             matlab.ui.control.NumericEditField
        StartdelayEditFieldLabel        matlab.ui.control.Label
        msLabel_11                      matlab.ui.control.Label
        ExposuretimeEditField           matlab.ui.control.NumericEditField
        ExposuretimeEditFieldLabel      matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        FramerateEditField              matlab.ui.control.NumericEditField
        FramerateEditFieldLabel         matlab.ui.control.Label
        RewardparametersPanel           matlab.ui.container.Panel
        LightrewardCheckBox             matlab.ui.control.CheckBox
        WhiskerrewardCheckBox           matlab.ui.control.CheckBox
        AuditoryrewardCheckBox          matlab.ui.control.CheckBox
        RewardprobabilityEditField      matlab.ui.control.NumericEditField
        RewardprobabilityEditFieldLabel  matlab.ui.control.Label
        PartialrewardCheckBox           matlab.ui.control.CheckBox
        msLabel_10                      matlab.ui.control.Label
        RewarddelayEditField            matlab.ui.control.NumericEditField
        RewarddelayCheckBox             matlab.ui.control.CheckBox
        ManualrewardButton              matlab.ui.control.Button
        msLabel_9                       matlab.ui.control.Label
        ValveopeningEditField           matlab.ui.control.NumericEditField
        ValveopeningEditFieldLabel      matlab.ui.control.Label
        TimeoutparametersPanel          matlab.ui.container.Panel
        msLabel_8                       matlab.ui.control.Label
        EarlylicktimeoutEditField       matlab.ui.control.NumericEditField
        EarlylicktimeoutCheckBox        matlab.ui.control.CheckBox
        msLabel_7                       matlab.ui.control.Label
        FalsealarmtimeoutEditField      matlab.ui.control.NumericEditField
        FalsealarmtimeoutCheckBox       matlab.ui.control.CheckBox
        TrialtimelineparametersPanel    matlab.ui.container.Panel
        msLabel_6                       matlab.ui.control.Label
        BaselinewindowEditField         matlab.ui.control.NumericEditField
        BaselinewindowEditFieldLabel    matlab.ui.control.Label
        msLabel_5                       matlab.ui.control.Label
        TrialdurationEditField          matlab.ui.control.NumericEditField
        TrialdurationEditFieldLabel     matlab.ui.control.Label
        msLabel_4                       matlab.ui.control.Label
        ResponsewindowEditField         matlab.ui.control.NumericEditField
        ResponsewindowEditFieldLabel    matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        ArtifactwindowEditField         matlab.ui.control.NumericEditField
        ArtifactwindowEditFieldLabel    matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        MaxITI                          matlab.ui.control.NumericEditField
        MinITI                          matlab.ui.control.NumericEditField
        IntertrialintervalEditFieldLabel  matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MaxQuietWindow                  matlab.ui.control.NumericEditField
        MinQuietWindow                  matlab.ui.control.NumericEditField
        QuietwindowEditFieldLabel       matlab.ui.control.Label
        GeneralsettingsPanel            matlab.ui.container.Panel
        ContexttableEditField           matlab.ui.control.EditField
        ContexttableEditFieldLabel      matlab.ui.control.Label
        MaxcontextrepeatEditField       matlab.ui.control.NumericEditField
        MaxcontextrepeatEditFieldLabel  matlab.ui.control.Label
        ContextCheckBox                 matlab.ui.control.CheckBox
        BehaviourtypeDropDown           matlab.ui.control.DropDown
        BehaviourtypeDropDownLabel      matlab.ui.control.Label
        DummysessionCheckBox            matlab.ui.control.CheckBox
        AssociationCheckBox             matlab.ui.control.CheckBox
        CameraONCheckBox                matlab.ui.control.CheckBox
        LSENSbehaviouralGUIcontrol20Label  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        MousenameweightPanel            matlab.ui.container.Panel
        DateEditField                   matlab.ui.control.EditField
        DateEditFieldLabel              matlab.ui.control.Label
        UpdateconfigurationButton       matlab.ui.control.Button
        gLabel_2                        matlab.ui.control.Label
        gLabel                          matlab.ui.control.Label
        WeightaftersessionEditField     matlab.ui.control.NumericEditField
        WeightaftersessionEditFieldLabel  matlab.ui.control.Label
        WeightbeforesessionEditField    matlab.ui.control.NumericEditField
        WeightbeforesessionEditFieldLabel  matlab.ui.control.Label
        BehaviourDirectory              matlab.ui.control.EditField
        BrowsedirectoryButton           matlab.ui.control.Button
        MousenameEditField              matlab.ui.control.EditField
        MousenameEditFieldLabel         matlab.ui.control.Label
        StopBehaviour                   matlab.ui.control.Button
        StartBehaviour                  matlab.ui.control.Button
        PerformanceandrewardsPanel      matlab.ui.container.Panel
        PerformanceText_3               matlab.ui.control.Label
        PerformanceText_2               matlab.ui.control.Label
        PerformanceText_1               matlab.ui.control.Label
        OnlineText                      matlab.ui.control.Label
        LastrecenttrialsEditField       matlab.ui.control.NumericEditField
        LastrecenttrialsEditFieldLabel  matlab.ui.control.Label
        EarlyLickAxes                   matlab.ui.control.UIAxes
        PerformanceAxes                 matlab.ui.control.UIAxes
        TrialtimelinePanel              matlab.ui.container.Panel
        TrialTimeLineText               matlab.ui.control.Label
        LicktresholdEditField           matlab.ui.control.NumericEditField
        LicktreshEditFieldLabel         matlab.ui.control.Label
        TrialStartTTL                   matlab.ui.control.UIAxes
        LickTraceAx                     matlab.ui.control.UIAxes
        Cam1Ax                          matlab.ui.control.UIAxes
        Cam2Ax                          matlab.ui.control.UIAxes
        ScanAx                          matlab.ui.control.UIAxes
        LightAx                         matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function set_trial_timeline_text(app, txt, color)
            app.TrialTimeLineText.Text = txt;
            app.TrialTimeLineText.FontColor = color;
        end

        function set_online_text(app, txt, color)
            app.OnlineText.Text = txt;
            app.OnlineText.FontColor = color;
        end

        function set_performance_1_text(app, txt, color)
            app.PerformanceText_1.Text = txt;
            app.PerformanceText_1.FontColor = color;
        end

        function set_performance_2_text(app, txt, color)
            app.PerformanceText_2.Text = txt;
            app.PerformanceText_2.FontColor = color;
        end

        function set_performance_3_text(app, txt, color)
            app.PerformanceText_3.Text = txt;
            app.PerformanceText_3.FontColor = color;
        end

        function set_passive_toggle(app, state)
            app.PassiveButton.Value = state;
            app.publish_state();
        end

        function reset_pause_flag(app)
            app.PauseRequestedFlag = false;
            app.publish_state();
        end

        function set_report_pause(app, state)
            app.ReportPauseFlag = state;
            app.publish_state();
        end

        function publish_state(app)
            global handles2give


            %% Axes - publish the live handle, downstream files plot to these directly
            handles2give.PerformanceAxes = app.PerformanceAxes;
            handles2give.EarlyLickAxes   = app.EarlyLickAxes;
            handles2give.LickTraceAx     = app.LickTraceAx;
            handles2give.Cam1Ax          = app.Cam1Ax;
            handles2give.Cam2Ax          = app.Cam2Ax;
            handles2give.ScanAx          = app.ScanAx;
            handles2give.LightAx         = app.LightAx;
            handles2give.TrialStartTTL   = app.TrialStartTTL;

            %% General / session info
            handles2give.mouse_name           = app.MousenameEditField.Value;
            handles2give.date                 = app.DateEditField.Value;
            handles2give.mouse_weight_before  = app.WeightbeforesessionEditField.Value;
            handles2give.mouse_weight_after   = app.WeightaftersessionEditField.Value;
            handles2give.behaviour_directory  = app.BehaviourDirectory.Value;
            handles2give.behaviour_type       = app.BehaviourtypeDropDown.Value;

            %% Trial timing
            handles2give.trial_duration    = app.TrialdurationEditField.Value;
            handles2give.min_quiet_window  = app.MinQuietWindow.Value;
            handles2give.max_quiet_window  = app.MaxQuietWindow.Value;
            handles2give.response_window   = app.ResponsewindowEditField.Value;
            handles2give.artifact_window   = app.ArtifactwindowEditField.Value;
            handles2give.baseline_window   = app.BaselinewindowEditField.Value;
            handles2give.max_iti           = app.MaxITI.Value;
            handles2give.min_iti           = app.MinITI.Value;

            %% Lick / camera
            handles2give.lick_threshold        = app.LicktresholdEditField.Value;
            handles2give.camera_flag           = app.CameraONCheckBox.Value;
            handles2give.camera_freq           = app.FramerateEditField.Value;
            handles2give.camera_exposure_time  = app.ExposuretimeEditField.Value;
            handles2give.camera_start_delay    = app.StartdelayEditField.Value;

            %% Punishment
            handles2give.false_alarm_punish_flag = app.FalsealarmtimeoutCheckBox.Value;
            handles2give.false_alarm_timeout     = app.FalsealarmtimeoutEditField.Value;
            handles2give.early_lick_punish_flag  = app.EarlylicktimeoutCheckBox.Value;
            handles2give.early_lick_timeout      = app.EarlylicktimeoutEditField.Value;

            %% Reward
            handles2give.reward_valve_duration = app.ValveopeningEditField.Value;
            handles2give.reward_delay_flag     = app.RewarddelayCheckBox.Value;
            handles2give.reward_delay_time = app.RewarddelayEditField.Value;
            handles2give.partial_reward_flag   = app.PartialrewardCheckBox.Value;
            handles2give.reward_proba          = app.RewardprobabilityEditField.Value;
            handles2give.aud_reward            = app.AuditoryrewardCheckBox.Value;
            handles2give.wh_reward             = app.WhiskerrewardCheckBox.Value;
            handles2give.light_reward          = app.LightrewardCheckBox.Value;

            %% Whisker stim
            handles2give.wh_stim_duration     = app.WhStimDuration.Value;
            handles2give.wh_scaling_factor    = app.WhStimScalingFactor.Value;
            handles2give.wh_stim_amp_range    = app.WhStimAmpRange.Value;
            handles2give.wh_stim_amp_1        = app.WhStimAmp_1.Value;
            handles2give.wh_stim_amp_2        = app.WhStimAmp_2.Value;
            handles2give.wh_stim_amp_3        = app.WhStimAmp_3.Value;
            handles2give.wh_stim_amp_4        = app.WhStimAmp_4.Value;
            handles2give.wh_stim_amp_5        = app.WhStimAmp_5.Value;
            handles2give.wh_stim_amp_mT_1     = app.WhStimAmp_1mT.Value;
            handles2give.wh_stim_amp_mT_2     = app.WhStimAmp_2mT.Value;
            handles2give.wh_stim_amp_mT_3     = app.WhStimAmp_3mT.Value;
            handles2give.wh_stim_amp_mT_4     = app.WhStimAmp_4mT.Value;
            handles2give.wh_stim_amp_mT_5     = app.WhStimAmp_5mT.Value;
            handles2give.wh_stim_weight_1     = app.WhStimWeight_1.Value;
            handles2give.wh_stim_weight_2     = app.WhStimWeight_2.Value;
            handles2give.wh_stim_weight_3     = app.WhStimWeight_3.Value;
            handles2give.wh_stim_weight_4     = app.WhStimWeight_4.Value;
            handles2give.wh_stim_weight_5     = app.WhStimWeight_5.Value;

            %% Auditory stim
            handles2give.aud_stim_amp      = app.AudStimAmplitude.Value;
            handles2give.aud_stim_duration = app.AudStimDuration.Value;
            handles2give.aud_stim_freq     = app.AudStimFrequency.Value;
            handles2give.aud_stim_weight   = app.AudStimWeight.Value;
            handles2give.context_sound_on       = app.ContextSoundON.Value;
            handles2give.bckg_noise_directory   = app.BackgroundNoiseDirectory.Value;

            %% Light stim
            handles2give.light_duration      = app.LightStimDuration.Value;
            handles2give.light_stim_amp_range = app.LightStimAmpRange.Value;
            handles2give.light_stim_amp_1    = app.LightStimAmp_1.Value;
            handles2give.light_stim_amp_2    = app.LightStimAmp_2.Value;
            handles2give.light_stim_amp_3    = app.LightStimAmp_3.Value;
            handles2give.light_stim_amp_4    = app.LightStimAmp_4.Value;
            handles2give.light_stim_amp_5    = app.LightStimAmp_5.Value;
            handles2give.light_stim_power_1  = app.LightStimPower_1.Value;
            handles2give.light_stim_power_2  = app.LightStimPower_2.Value;
            handles2give.light_stim_power_3  = app.LightStimPower_3.Value;
            handles2give.light_stim_power_4  = app.LightStimPower_4.Value;
            handles2give.light_stim_power_5  = app.LightStimPower_5.Value;
            handles2give.light_stim_weight_1 = app.LightStimWeight_1.Value;
            handles2give.light_stim_weight_2 = app.LightStimWeight_2.Value;
            handles2give.light_stim_weight_3 = app.LightStimWeight_3.Value;
            handles2give.light_stim_weight_4 = app.LightStimWeight_4.Value;
            handles2give.light_stim_weight_5 = app.LightStimWeight_5.Value;

            %% No-stim
            handles2give.no_stim_weight = app.NostimulusweightEditField.Value;

            %% Association / context
            handles2give.association_flag        = app.AssociationCheckBox.Value;
            handles2give.context_flag            = app.ContextCheckBox.Value;
            handles2give.context_block_size      = app.MaxcontextrepeatEditField.Value;
            handles2give.context_table_directory = app.ContexttableEditField.Value;

            %% Passive stim
            handles2give.passive_stim_flag_enable = app.PassivestimulationCheckBox.Value;
            handles2give.passive_stim_flag        = app.PassiveButton.Value;
            handles2give.passive_trial_max        = app.TrialsEditField.Value;
            handles2give.passive_iti              = app.IntertrialintervalEditField.Value;

            %% Session type flags
            handles2give.ttl_session   = app.PdCOCheckBox.Value;
            handles2give.pharma_session = app.PharmaCheckBox.Value;
            handles2give.chemo_session  = app.ChemoCheckBox.Value;
            handles2give.opto_session   = app.OptoCheckBox.Value;
            handles2give.wf_session     = app.WFCheckBox.Value;
            handles2give.dummy_session_flag = app.DummysessionCheckBox.Value;
            handles2give.intracell_ephys_session = app.IntracellephysCheckBox.Value;
            handles2give.extracell_ephys_session = app.ExtracellephysCheckBox.Value;
            handles2give.twophoton_session   = app.TwophotonCheckBox.Value;
            handles2give.threephoton_session = app.ThreephotonCheckBox.Value;

            %% Misc GUI state
            handles2give.last_recent_trials = app.LastrecenttrialsEditField.Value;
            handles2give.PauseRequested = app.PauseRequestedFlag;
            handles2give.ReportPause = app.ReportPauseFlag;

        end

    end

    properties (Access = private)
        PauseRequestedFlag = false
        ReportPauseFlag = false
    end
    
    properties (Access = public)
    end

    methods (Access = private)

        function set_components_enable(~, comps, state)
            for i = 1:numel(comps)
                comps{i}.Enable = state;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global main_gui
            main_gui = app;

            movegui(app.UIFigure, 'center');

            app.BehaviourDirectory.Value = 'D:\Behavior\BehaviourData';
            app.MousenameEditField.Value = 'MPXXX';
            app.DateEditField.Value = char(datetime('now','Format','yyyyMMdd'));

            app.WhStimAmpRangeValueChanged([]);
            app.LightStimAmpRangeValueChanged([]);

            app.LickTraceAx.PositionConstraint = 'innerposition';
            app.LightAx.PositionConstraint = 'innerposition';
            app.Cam1Ax.PositionConstraint = 'innerposition';
            app.Cam2Ax.PositionConstraint = 'innerposition';
            app.ScanAx.PositionConstraint = 'innerposition';
            app.TrialStartTTL.PositionConstraint = 'innerposition';
            app.PerformanceAxes.PositionConstraint = 'innerposition';
            app.EarlyLickAxes.PositionConstraint = 'innerposition';

            app.TrialStartTTL.YLim = [-1 8];
            app.LickTraceAx.YLim = [0, app.LicktresholdEditField.Value * 5];
            app.Cam1Ax.YLim = [-1 8];
            app.Cam2Ax.YLim = [-1 8];
            app.ScanAx.YLim = [-2 3];
            app.LightAx.YLim = [-1 8];

            app.publish_state();
        end

        % Value changed function: ArtifactwindowEditField, 
        % ...and 65 other components
        function genericFieldChanged(app, event)
            app.publish_state();
        end

        % Value changed function: LightStimAmpRange
        function LightStimAmpRangeValueChanged(app, event)
            state = matlab.lang.OnOffSwitchState(app.LightStimAmpRange.Value);
            app.set_components_enable({ ...
                app.LightStimAmp_2, app.LightStimPower_2, app.LightStimWeight_2, ...
                app.LightStimAmp_3, app.LightStimPower_3, app.LightStimWeight_3, ...
                app.LightStimAmp_4, app.LightStimPower_4, app.LightStimWeight_4, ...
                app.LightStimAmp_5, app.LightStimPower_5, app.LightStimWeight_5}, state);
            app.publish_state();
        end

        % Value changed function: WhStimAmpRange
        function WhStimAmpRangeValueChanged(app, event)
            state = matlab.lang.OnOffSwitchState(app.WhStimAmpRange.Value);
            app.set_components_enable({ ...
                app.WhStimAmp_2, app.WhStimAmp_2mT, app.WhStimWeight_2, ...
                app.WhStimAmp_3, app.WhStimAmp_3mT, app.WhStimWeight_3, ...
                app.WhStimAmp_4, app.WhStimAmp_4mT, app.WhStimWeight_4, ...
                app.WhStimAmp_5, app.WhStimAmp_5mT, app.WhStimWeight_5}, state);
            app.publish_state();
        end

        % Value changed function: PdCOCheckBox
        function PdCOCheckBoxValueChanged(app, event)
            global ttl_gui

            if app.PdCOCheckBox.Value
                thisFilePath = fileparts(mfilename('fullpath'));
                addpath(fullfile(thisFilePath, 'utils_TTL'))
                ttl_gui = TTL_GUI;
            else
                global TTL_info
                TTL_info = [];
                try
                    if exist('ttl_gui','var') && ~isempty(ttl_gui) && isvalid(ttl_gui)
                        ttl_gui.delete();
                    end
                catch
                end
                clear ttl_gui
            end

            app.publish_state();
        end

        % Value changed function: PharmaCheckBox
        function PharmaCheckBoxValueChanged(app, event)
            app.publish_state();
        end

        % Value changed function: ChemoCheckBox
        function ChemoCheckBoxValueChanged(app, event)
            app.publish_state();
        end

        % Value changed function: OptoCheckBox
        function OptoCheckBoxValueChanged(app, event)
            global opto_gui

            if app.OptoCheckBox.Value
                addpath([cd '\utils_opto\'])
                opto_gui = Opto_GUI;
            else
                try
                    if exist('opto_gui','var') && ~isempty(opto_gui) && isvalid(opto_gui)
                        opto_gui.delete();
                    end
                catch
                end
                clear opto_gui
            end

            app.publish_state();
        end

        % Value changed function: IntracellephysCheckBox
        function IntracellephysCheckBoxValueChanged(app, event)
            app.publish_state();
        end

        % Value changed function: ExtracellephysCheckBox
        function ExtracellephysCheckBoxValueChanged(app, event)
            app.publish_state();
        end

        % Value changed function: WFCheckBox
        function WFCheckBoxValueChanged(app, event)
            global wf_gui

            if app.WFCheckBox.Value
                addpath([fileparts(cd) '\WF_imaging\'])
                wf_gui = WF_GUI;
            else
                try
                    if exist('wf_gui','var') && ~isempty(wf_gui) && isvalid(wf_gui)
                        wf_gui.delete();
                    end
                catch
                end
                clear wf_gui
            end

            app.publish_state();
        end

        % Value changed function: ThreephotonCheckBox
        function ThreephotonCheckBoxValueChanged(app, event)
            app.publish_state();
        end

        % Value changed function: TwophotonCheckBox
        function TwophotonCheckBoxValueChanged(app, event)
            app.publish_state();
        end

        % Value changed function: PassivestimulationCheckBox
        function PassivestimulationCheckBoxValueChanged(app, event)
            state = matlab.lang.OnOffSwitchState(app.PassivestimulationCheckBox.Value);
            app.set_components_enable({ ...
                app.PassiveButton, app.TrialsEditField, app.IntertrialintervalEditField}, state);
            app.publish_state();
        end

        % Button pushed function: StartBehaviour
        function StartBehaviourButtonPushed(app, event)
            global handles2give

            % Ensure published state reflects current GUI values before anything reads it
            app.publish_state();

            % Session timestamp
            now_dt = datetime('now');
            handles2give.session_time = char(datetime(now_dt,'Format','HHmmss'));

            app.DateEditField.Value = char(datetime(now_dt,'Format','yyyyMMdd'));
            app.publish_state();

            % Create session directory
            mouse_dir = fullfile(char(handles2give.behaviour_directory), char(handles2give.mouse_name));
            if ~exist(mouse_dir, 'dir')
                mkdir(char(handles2give.behaviour_directory), char(handles2give.mouse_name));
            end
            session_dir_name = [char(handles2give.mouse_name) '_' char(handles2give.date) '_' handles2give.session_time];
            if ~exist(fullfile(mouse_dir, session_dir_name), 'dir')
                mkdir(mouse_dir, session_dir_name);
            end

            % Clear GUI axes - internal to the class, use app's own component names directly
            cla(app.TrialStartTTL);
            cla(app.Cam1Ax);
            cla(app.Cam2Ax);
            cla(app.ScanAx);
            cla(app.LightAx);
            cla(app.PerformanceAxes);
            cla(app.LickTraceAx);
            cla(app.EarlyLickAxes);

            % Clear status/performance text
            app.set_performance_1_text(' ', [0 0 0]);
            app.set_performance_2_text(' ', [0 0 0]);
            app.set_performance_3_text(' ', [0 0 0]);
            app.set_online_text(' ', [0 0 0]);
            app.set_trial_timeline_text(' ', [0 0 0]);

            % Widefield session interlock
            if handles2give.wf_session
                global wf_gui
                wf_gui.DirectoryEditField.Enable = 'off';
                wf_gui.TriggermodeDropDown.Enable = 'off';
                wf_gui.CameramodeDropDown.Enable = 'off';
                wf_gui.ONCheckBox.Enable = 'off';
                wf_gui.ONCheckBox_2.Enable = 'off';
                wf_gui.Switch.Enable = 'off';
                wf_gui.Switch_2.Enable = 'off';
                wf_gui.update_WF_info();
            end

            % Opto session interlock
            if handles2give.opto_session
                global opto_gui
                opto_gui.GridDropDown.Enable = 'off';
                opto_gui.AssignbregmaCheckBox.Enable = 'off';
                opto_gui.update_opto_info();
            end

            % TTL/PdCO session: push latest TTL GUI state before session creation
            % (old code had this block duplicated - deduped to one here)
            if isfield(handles2give,'ttl_session') && handles2give.ttl_session
                global ttl_gui
                try
                    if ~isempty(ttl_gui) && isvalid(ttl_gui)
                        ttl_gui.update_ttl_info();
                    end
                catch
                    disp('Could not update TTL GUI info before session start.')
                end
            end

            % Save session config and start hardware sessions
            save_session_config(handles2give);
            defining_sessions;
        end

        % Button pushed function: StopBehaviour
        function StopBehaviourButtonPushed(app, event)
            stop_sessions;

            global handles2give
            if handles2give.wf_session
                global wf_gui
                wf_gui.DirectoryEditField.Enable = 'on';
                wf_gui.TriggermodeDropDown.Enable = 'on';
                wf_gui.CameramodeDropDown.Enable = 'on';
                wf_gui.ONCheckBox.Enable = 'on';
                wf_gui.ONCheckBox_2.Enable = 'on';
                wf_gui.Switch.Enable = 'on';
                wf_gui.Switch_2.Enable = 'on';
            end
            if handles2give.opto_session
                global opto_gui
                opto_gui.GridDropDown.Enable = 'on';
                opto_gui.AssignbregmaCheckBox.Enable = 'on';
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.PauseRequestedFlag = true;
            app.ReportPauseFlag = true;
            app.publish_state();
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.PauseRequestedFlag = false;
            app.publish_state();
        end

        % Button pushed function: BrowsedirectoryButton
        function BrowsedirectoryButtonPushed(app, event)
            selected_dir = uigetdir;
            if selected_dir ~= 0   % uigetdir returns 0 if the user cancels the dialog
                app.BehaviourDirectory.Value = selected_dir;
                app.publish_state();
            end
        end

        % Button pushed function: ManualrewardButton
        function ManualrewardButtonPushed(app, event)
            manual_reward_delivery;
        end

        % Value changed function: CameraONCheckBox
        function CameraONCheckBoxValueChanged(app, event)
            state = matlab.lang.OnOffSwitchState(app.CameraONCheckBox.Value);
            app.set_components_enable({ ...
                app.FramerateEditField, app.StartdelayEditField, app.ExposuretimeEditField}, state);
            app.publish_state();
        end

        % Value changed function: RewarddelayCheckBox
        function RewarddelayCheckBoxValueChanged(app, event)
            app.RewarddelayEditField.Enable = matlab.lang.OnOffSwitchState(app.RewarddelayCheckBox.Value);
            app.publish_state();
        end

        % Value changed function: PartialrewardCheckBox
        function PartialrewardCheckBoxValueChanged(app, event)
            app.RewardprobabilityEditField.Enable = matlab.lang.OnOffSwitchState(app.PartialrewardCheckBox.Value);
            app.publish_state();
        end

        % Value changed function: ContextCheckBox
        function ContextCheckBoxValueChanged(app, event)
            state = matlab.lang.OnOffSwitchState(app.ContextCheckBox.Value);
            app.set_components_enable({ ...
                app.MaxcontextrepeatEditField, app.ContexttableEditField, ...
                app.BackgroundNoiseDirectory, app.ContextSoundON}, state);

            % Context mode forces whisker reward on and locks it - context table
            % determines per-block reward, not the checkbox
            app.WhiskerrewardCheckBox.Value = true;
            app.WhiskerrewardCheckBox.Enable = ~app.ContextCheckBox.Value;

            app.publish_state();
        end

        % Button pushed function: UpdateconfigurationButton
        function UpdateconfigurationButtonPushed(app, event)
            global handles2give

            % Ensure published state reflects current field values before saving
            app.publish_state();

            save_session_config(handles2give);
            disp('Updated config file.');
        end

        % Value changed function: LicktresholdEditField
        function LicktresholdEditFieldValueChanged(app, event)
            app.LickTraceAx.YLim = [0, app.LicktresholdEditField.Value * 5];
            app.publish_state();
        end

        % Value changed function: FalsealarmtimeoutCheckBox
        function FalsealarmtimeoutCheckBoxValueChanged(app, event)
            app.FalsealarmtimeoutEditField.Enable = matlab.lang.OnOffSwitchState(app.FalsealarmtimeoutCheckBox.Value);
            app.publish_state();
        end

        % Value changed function: EarlylicktimeoutCheckBox
        function EarlylicktimeoutCheckBoxValueChanged(app, event)
            app.EarlylicktimeoutEditField.Enable = matlab.lang.OnOffSwitchState(app.EarlylicktimeoutCheckBox.Value);
            app.publish_state();
        end

        % Value changed function: BaselinewindowEditField, 
        % ...and 2 other components
        function TrialTimingValidationChanged(app, event)
            time_limit = app.BaselinewindowEditField.Value + app.ResponsewindowEditField.Value;
            if app.TrialdurationEditField.Value < time_limit
                disp('Trial duration cannot be shorter than baseline + response window. Setting sum of two as default.');
                app.TrialdurationEditField.Value = time_limit;
            end
            app.publish_state();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrialtimelinePanel = uipanel(app.UIFigure);
            app.TrialtimelinePanel.Title = 'Trial timeline';
            app.LightAx = uiaxes(app.TrialtimelinePanel);
            ylabel(app.LightAx, 'PdCO')
            zlabel(app.LightAx, 'Z')
            app.ScanAx = uiaxes(app.TrialtimelinePanel);
            ylabel(app.ScanAx, 'Galvo')
            zlabel(app.ScanAx, 'Z')
            app.Cam2Ax = uiaxes(app.TrialtimelinePanel);
            ylabel(app.Cam2Ax, 'Cam 2')
            zlabel(app.Cam2Ax, 'Z')
            app.Cam1Ax = uiaxes(app.TrialtimelinePanel);
            ylabel(app.Cam1Ax, 'Cam 1')
            zlabel(app.Cam1Ax, 'Z')
            app.LickTraceAx = uiaxes(app.TrialtimelinePanel);
            ylabel(app.LickTraceAx, 'Lick')
            zlabel(app.LickTraceAx, 'Z')
            app.TrialStartTTL = uiaxes(app.TrialtimelinePanel);
            ylabel(app.TrialStartTTL, 'Trial TTL')
            zlabel(app.TrialStartTTL, 'Z')
            app.LicktreshEditFieldLabel = uilabel(app.TrialtimelinePanel);
            app.LicktreshEditFieldLabel.Text = 'Lick tresh.';
            app.LicktresholdEditField = uieditfield(app.TrialtimelinePanel, 'numeric');
            app.LicktresholdEditField.ValueChangedFcn = createCallbackFcn(app, @LicktresholdEditFieldValueChanged, true);
            app.LicktresholdEditField.Value = 0.03;
            app.TrialTimeLineText = uilabel(app.TrialtimelinePanel);
            app.TrialTimeLineText.Text = '';
            app.PerformanceandrewardsPanel = uipanel(app.UIFigure);
            app.PerformanceandrewardsPanel.Title = 'Performance and rewards';
            app.PerformanceAxes = uiaxes(app.PerformanceandrewardsPanel);
            app.EarlyLickAxes = uiaxes(app.PerformanceandrewardsPanel);
            app.LastrecenttrialsEditFieldLabel = uilabel(app.PerformanceandrewardsPanel);
            app.LastrecenttrialsEditFieldLabel.Text = 'Last recent trials';
            app.LastrecenttrialsEditField = uieditfield(app.PerformanceandrewardsPanel, 'numeric');
            app.LastrecenttrialsEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LastrecenttrialsEditField.Value = 20;
            app.OnlineText = uilabel(app.PerformanceandrewardsPanel);
            app.OnlineText.Text = '';
            app.PerformanceText_1 = uilabel(app.PerformanceandrewardsPanel);
            app.PerformanceText_1.Text = '';
            app.PerformanceText_2 = uilabel(app.PerformanceandrewardsPanel);
            app.PerformanceText_2.Text = '';
            app.PerformanceText_3 = uilabel(app.PerformanceandrewardsPanel);
            app.PerformanceText_3.Text = '';
            app.StartBehaviour = uibutton(app.UIFigure, 'push');
            app.StartBehaviour.ButtonPushedFcn = createCallbackFcn(app, @StartBehaviourButtonPushed, true);
            app.StartBehaviour.Text = 'Start';
            app.StopBehaviour = uibutton(app.UIFigure, 'push');
            app.StopBehaviour.ButtonPushedFcn = createCallbackFcn(app, @StopBehaviourButtonPushed, true);
            app.StopBehaviour.Text = 'Stop';
            app.MousenameweightPanel = uipanel(app.UIFigure);
            app.MousenameweightPanel.Title = 'Mouse name & weight';
            app.MousenameEditFieldLabel = uilabel(app.MousenameweightPanel);
            app.MousenameEditFieldLabel.Text = 'Mouse name';
            app.MousenameEditField = uieditfield(app.MousenameweightPanel, 'text');
            app.BrowsedirectoryButton = uibutton(app.MousenameweightPanel, 'push');
            app.BrowsedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsedirectoryButtonPushed, true);
            app.BrowsedirectoryButton.Text = 'Browse directory';
            app.BehaviourDirectory = uieditfield(app.MousenameweightPanel, 'text');
            app.WeightbeforesessionEditFieldLabel = uilabel(app.MousenameweightPanel);
            app.WeightbeforesessionEditFieldLabel.Text = 'Weight before session';
            app.WeightbeforesessionEditField = uieditfield(app.MousenameweightPanel, 'numeric');
            app.WeightbeforesessionEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WeightaftersessionEditFieldLabel = uilabel(app.MousenameweightPanel);
            app.WeightaftersessionEditFieldLabel.Text = 'Weight after session';
            app.WeightaftersessionEditField = uieditfield(app.MousenameweightPanel, 'numeric');
            app.WeightaftersessionEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.gLabel = uilabel(app.MousenameweightPanel);
            app.gLabel.Text = '[g]';
            app.gLabel_2 = uilabel(app.MousenameweightPanel);
            app.gLabel_2.Text = '[g]';
            app.UpdateconfigurationButton = uibutton(app.MousenameweightPanel, 'push');
            app.UpdateconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateconfigurationButtonPushed, true);
            app.UpdateconfigurationButton.Text = 'Update configuration';
            app.DateEditFieldLabel = uilabel(app.MousenameweightPanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.MousenameweightPanel, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '60670682.png');
            app.LSENSbehaviouralGUIcontrol20Label = uilabel(app.UIFigure);
            app.LSENSbehaviouralGUIcontrol20Label.Text = 'LSENS behavioural GUI control 2.0';
            app.GeneralsettingsPanel = uipanel(app.UIFigure);
            app.GeneralsettingsPanel.Title = 'General settings';
            app.CameraONCheckBox = uicheckbox(app.GeneralsettingsPanel);
            app.CameraONCheckBox.ValueChangedFcn = createCallbackFcn(app, @CameraONCheckBoxValueChanged, true);
            app.CameraONCheckBox.Text = 'Camera ON';
            app.AssociationCheckBox = uicheckbox(app.GeneralsettingsPanel);
            app.AssociationCheckBox.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.AssociationCheckBox.Text = 'Association';
            app.DummysessionCheckBox = uicheckbox(app.GeneralsettingsPanel);
            app.DummysessionCheckBox.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.DummysessionCheckBox.Text = 'Dummy session';
            app.BehaviourtypeDropDownLabel = uilabel(app.GeneralsettingsPanel);
            app.BehaviourtypeDropDownLabel.Text = 'Behaviour type:';
            app.BehaviourtypeDropDown = uidropdown(app.GeneralsettingsPanel);
            app.BehaviourtypeDropDown.Items = {'free_licking', 'auditory', 'light', 'whisker', 'whisker_context', 'whisker_psy', 'whisker_on_1', 'whisker_off', 'whisker_on_2', 'light_psy', 'light_on_1', 'light_off', 'light_on_2'};
            app.BehaviourtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.BehaviourtypeDropDown.Value = 'free_licking';
            app.ContextCheckBox = uicheckbox(app.GeneralsettingsPanel);
            app.ContextCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContextCheckBoxValueChanged, true);
            app.ContextCheckBox.Text = 'Context';
            app.MaxcontextrepeatEditFieldLabel = uilabel(app.GeneralsettingsPanel);
            app.MaxcontextrepeatEditFieldLabel.Text = 'Max context repeat:';
            app.MaxcontextrepeatEditField = uieditfield(app.GeneralsettingsPanel, 'numeric');
            app.MaxcontextrepeatEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.MaxcontextrepeatEditField.Enable = 'off';
            app.MaxcontextrepeatEditField.Value = 1;
            app.ContexttableEditFieldLabel = uilabel(app.GeneralsettingsPanel);
            app.ContexttableEditFieldLabel.Text = 'Context table:';
            app.ContexttableEditField = uieditfield(app.GeneralsettingsPanel, 'text');
            app.ContexttableEditField.Enable = 'off';
            app.TrialtimelineparametersPanel = uipanel(app.UIFigure);
            app.TrialtimelineparametersPanel.Title = 'Trial timeline parameters';
            app.QuietwindowEditFieldLabel = uilabel(app.TrialtimelineparametersPanel);
            app.QuietwindowEditFieldLabel.Text = 'Quiet window';
            app.MinQuietWindow = uieditfield(app.TrialtimelineparametersPanel, 'numeric');
            app.MinQuietWindow.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.MinQuietWindow.Value = 2000;
            app.MaxQuietWindow = uieditfield(app.TrialtimelineparametersPanel, 'numeric');
            app.MaxQuietWindow.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.MaxQuietWindow.Value = 5000;
            app.Label = uilabel(app.TrialtimelineparametersPanel);
            app.Label.Text = '-';
            app.msLabel = uilabel(app.TrialtimelineparametersPanel);
            app.msLabel.Text = '[ms]';
            app.IntertrialintervalEditFieldLabel = uilabel(app.TrialtimelineparametersPanel);
            app.IntertrialintervalEditFieldLabel.Text = 'Inter-trial interval';
            app.MinITI = uieditfield(app.TrialtimelineparametersPanel, 'numeric');
            app.MinITI.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.MinITI.Value = 6000;
            app.MaxITI = uieditfield(app.TrialtimelineparametersPanel, 'numeric');
            app.MaxITI.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.MaxITI.Value = 10000;
            app.Label_2 = uilabel(app.TrialtimelineparametersPanel);
            app.Label_2.Text = '-';
            app.msLabel_2 = uilabel(app.TrialtimelineparametersPanel);
            app.msLabel_2.Text = '[ms]';
            app.ArtifactwindowEditFieldLabel = uilabel(app.TrialtimelineparametersPanel);
            app.ArtifactwindowEditFieldLabel.Text = 'Artifact window';
            app.ArtifactwindowEditField = uieditfield(app.TrialtimelineparametersPanel, 'numeric');
            app.ArtifactwindowEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.ArtifactwindowEditField.Value = 100;
            app.msLabel_3 = uilabel(app.TrialtimelineparametersPanel);
            app.msLabel_3.Text = '[ms]';
            app.ResponsewindowEditFieldLabel = uilabel(app.TrialtimelineparametersPanel);
            app.ResponsewindowEditFieldLabel.Text = 'Response window';
            app.ResponsewindowEditField = uieditfield(app.TrialtimelineparametersPanel, 'numeric');
            app.ResponsewindowEditField.ValueChangedFcn = createCallbackFcn(app, @TrialTimingValidationChanged, true);
            app.ResponsewindowEditField.Value = 1000;
            app.msLabel_4 = uilabel(app.TrialtimelineparametersPanel);
            app.msLabel_4.Text = '[ms]';
            app.TrialdurationEditFieldLabel = uilabel(app.TrialtimelineparametersPanel);
            app.TrialdurationEditFieldLabel.Text = 'Trial duration';
            app.TrialdurationEditField = uieditfield(app.TrialtimelineparametersPanel, 'numeric');
            app.TrialdurationEditField.ValueChangedFcn = createCallbackFcn(app, @TrialTimingValidationChanged, true);
            app.TrialdurationEditField.Value = 5000;
            app.msLabel_5 = uilabel(app.TrialtimelineparametersPanel);
            app.msLabel_5.Text = '[ms]';
            app.BaselinewindowEditFieldLabel = uilabel(app.TrialtimelineparametersPanel);
            app.BaselinewindowEditFieldLabel.Text = 'Baseline window';
            app.BaselinewindowEditField = uieditfield(app.TrialtimelineparametersPanel, 'numeric');
            app.BaselinewindowEditField.ValueChangedFcn = createCallbackFcn(app, @TrialTimingValidationChanged, true);
            app.msLabel_6 = uilabel(app.TrialtimelineparametersPanel);
            app.msLabel_6.Text = '[ms]';
            app.TimeoutparametersPanel = uipanel(app.UIFigure);
            app.TimeoutparametersPanel.Title = 'Timeout parameters';
            app.FalsealarmtimeoutCheckBox = uicheckbox(app.TimeoutparametersPanel);
            app.FalsealarmtimeoutCheckBox.ValueChangedFcn = createCallbackFcn(app, @FalsealarmtimeoutCheckBoxValueChanged, true);
            app.FalsealarmtimeoutCheckBox.Text = 'False alarm timeout';
            app.FalsealarmtimeoutEditField = uieditfield(app.TimeoutparametersPanel, 'numeric');
            app.FalsealarmtimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.FalsealarmtimeoutEditField.Enable = 'off';
            app.msLabel_7 = uilabel(app.TimeoutparametersPanel);
            app.msLabel_7.Text = '[ms]';
            app.EarlylicktimeoutCheckBox = uicheckbox(app.TimeoutparametersPanel);
            app.EarlylicktimeoutCheckBox.ValueChangedFcn = createCallbackFcn(app, @EarlylicktimeoutCheckBoxValueChanged, true);
            app.EarlylicktimeoutCheckBox.Text = 'Early lick timeout';
            app.EarlylicktimeoutEditField = uieditfield(app.TimeoutparametersPanel, 'numeric');
            app.EarlylicktimeoutEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.EarlylicktimeoutEditField.Enable = 'off';
            app.msLabel_8 = uilabel(app.TimeoutparametersPanel);
            app.msLabel_8.Text = '[ms]';
            app.RewardparametersPanel = uipanel(app.UIFigure);
            app.RewardparametersPanel.Title = 'Reward parameters';
            app.ValveopeningEditFieldLabel = uilabel(app.RewardparametersPanel);
            app.ValveopeningEditFieldLabel.Text = 'Valve opening';
            app.ValveopeningEditField = uieditfield(app.RewardparametersPanel, 'numeric');
            app.ValveopeningEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.ValveopeningEditField.Value = 50;
            app.msLabel_9 = uilabel(app.RewardparametersPanel);
            app.msLabel_9.Text = '[ms]';
            app.ManualrewardButton = uibutton(app.RewardparametersPanel, 'push');
            app.ManualrewardButton.ButtonPushedFcn = createCallbackFcn(app, @ManualrewardButtonPushed, true);
            app.ManualrewardButton.Text = 'Manual reward';
            app.RewarddelayCheckBox = uicheckbox(app.RewardparametersPanel);
            app.RewarddelayCheckBox.ValueChangedFcn = createCallbackFcn(app, @RewarddelayCheckBoxValueChanged, true);
            app.RewarddelayCheckBox.Text = 'Reward delay';
            app.RewarddelayEditField = uieditfield(app.RewardparametersPanel, 'numeric');
            app.RewarddelayEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.RewarddelayEditField.Enable = 'off';
            app.msLabel_10 = uilabel(app.RewardparametersPanel);
            app.msLabel_10.Text = '[ms]';
            app.PartialrewardCheckBox = uicheckbox(app.RewardparametersPanel);
            app.PartialrewardCheckBox.ValueChangedFcn = createCallbackFcn(app, @PartialrewardCheckBoxValueChanged, true);
            app.PartialrewardCheckBox.Text = 'Partial reward';
            app.RewardprobabilityEditFieldLabel = uilabel(app.RewardparametersPanel);
            app.RewardprobabilityEditFieldLabel.Text = 'Reward probability';
            app.RewardprobabilityEditField = uieditfield(app.RewardparametersPanel, 'numeric');
            app.RewardprobabilityEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.RewardprobabilityEditField.Enable = 'off';
            app.RewardprobabilityEditField.Value = 1;
            app.AuditoryrewardCheckBox = uicheckbox(app.RewardparametersPanel);
            app.AuditoryrewardCheckBox.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.AuditoryrewardCheckBox.Text = 'Auditory reward';
            app.AuditoryrewardCheckBox.Value = true;
            app.WhiskerrewardCheckBox = uicheckbox(app.RewardparametersPanel);
            app.WhiskerrewardCheckBox.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhiskerrewardCheckBox.Text = 'Whisker reward';
            app.WhiskerrewardCheckBox.Value = true;
            app.LightrewardCheckBox = uicheckbox(app.RewardparametersPanel);
            app.LightrewardCheckBox.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightrewardCheckBox.Text = 'Light reward';
            app.LightrewardCheckBox.Value = true;
            app.BehaviourcamerasettingsPanel = uipanel(app.UIFigure);
            app.BehaviourcamerasettingsPanel.Title = 'Behaviour camera settings';
            app.FramerateEditFieldLabel = uilabel(app.BehaviourcamerasettingsPanel);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.BehaviourcamerasettingsPanel, 'numeric');
            app.FramerateEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.FramerateEditField.Enable = 'off';
            app.FramerateEditField.Value = 200;
            app.HzLabel = uilabel(app.BehaviourcamerasettingsPanel);
            app.HzLabel.Text = '[Hz]';
            app.ExposuretimeEditFieldLabel = uilabel(app.BehaviourcamerasettingsPanel);
            app.ExposuretimeEditFieldLabel.Text = 'Exposure time';
            app.ExposuretimeEditField = uieditfield(app.BehaviourcamerasettingsPanel, 'numeric');
            app.ExposuretimeEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.ExposuretimeEditField.Enable = 'off';
            app.ExposuretimeEditField.Value = 2;
            app.msLabel_11 = uilabel(app.BehaviourcamerasettingsPanel);
            app.msLabel_11.Text = '[ms]';
            app.StartdelayEditFieldLabel = uilabel(app.BehaviourcamerasettingsPanel);
            app.StartdelayEditFieldLabel.Text = 'Start delay';
            app.StartdelayEditField = uieditfield(app.BehaviourcamerasettingsPanel, 'numeric');
            app.StartdelayEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.StartdelayEditField.Enable = 'off';
            app.StartdelayEditField.Value = 3;
            app.sLabel = uilabel(app.BehaviourcamerasettingsPanel);
            app.sLabel.Text = '[s]';
            app.PassivestimulationPanel = uipanel(app.UIFigure);
            app.PassivestimulationPanel.Title = 'Passive stimulation';
            app.PassivestimulationCheckBox = uicheckbox(app.PassivestimulationPanel);
            app.PassivestimulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PassivestimulationCheckBoxValueChanged, true);
            app.PassivestimulationCheckBox.Text = 'Passive stimulation';
            app.TrialsEditFieldLabel = uilabel(app.PassivestimulationPanel);
            app.TrialsEditFieldLabel.Text = 'Trials';
            app.TrialsEditField = uieditfield(app.PassivestimulationPanel, 'numeric');
            app.TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.TrialsEditField.Enable = 'off';
            app.TrialsEditField.Value = 40;
            app.IntertrialintervalEditFieldLabel_2 = uilabel(app.PassivestimulationPanel);
            app.IntertrialintervalEditFieldLabel_2.Text = 'Inter-trial interval';
            app.IntertrialintervalEditField = uieditfield(app.PassivestimulationPanel, 'numeric');
            app.IntertrialintervalEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.IntertrialintervalEditField.Enable = 'off';
            app.IntertrialintervalEditField.Value = 3000;
            app.msLabel_12 = uilabel(app.PassivestimulationPanel);
            app.msLabel_12.Text = '[ms]';
            app.PausepreparethenLabel = uilabel(app.PassivestimulationPanel);
            app.PausepreparethenLabel.Text = {'Pause, prepare'; 'then'};
            app.thenresumeLabel = uilabel(app.PassivestimulationPanel);
            app.thenresumeLabel.Text = 'then resume';
            app.PassiveButton = uibutton(app.PassivestimulationPanel, 'state');
            app.PassiveButton.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.PassiveButton.Enable = 'off';
            app.PassiveButton.Text = 'Passive';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.SessiontypePanel = uipanel(app.UIFigure);
            app.SessiontypePanel.Title = 'Session type';
            app.TwophotonCheckBox = uicheckbox(app.SessiontypePanel);
            app.TwophotonCheckBox.ValueChangedFcn = createCallbackFcn(app, @TwophotonCheckBoxValueChanged, true);
            app.TwophotonCheckBox.Text = 'Two-photon';
            app.ThreephotonCheckBox = uicheckbox(app.SessiontypePanel);
            app.ThreephotonCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThreephotonCheckBoxValueChanged, true);
            app.ThreephotonCheckBox.Text = 'Three-photon';
            app.WFCheckBox = uicheckbox(app.SessiontypePanel);
            app.WFCheckBox.ValueChangedFcn = createCallbackFcn(app, @WFCheckBoxValueChanged, true);
            app.WFCheckBox.Text = 'WF';
            app.ExtracellephysCheckBox = uicheckbox(app.SessiontypePanel);
            app.ExtracellephysCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExtracellephysCheckBoxValueChanged, true);
            app.ExtracellephysCheckBox.Text = 'Extracell. ephys';
            app.IntracellephysCheckBox = uicheckbox(app.SessiontypePanel);
            app.IntracellephysCheckBox.ValueChangedFcn = createCallbackFcn(app, @IntracellephysCheckBoxValueChanged, true);
            app.IntracellephysCheckBox.Text = 'Intracell. ephys';
            app.OptoCheckBox = uicheckbox(app.SessiontypePanel);
            app.OptoCheckBox.ValueChangedFcn = createCallbackFcn(app, @OptoCheckBoxValueChanged, true);
            app.OptoCheckBox.Text = 'Opto';
            app.ChemoCheckBox = uicheckbox(app.SessiontypePanel);
            app.ChemoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChemoCheckBoxValueChanged, true);
            app.ChemoCheckBox.Text = 'Chemo';
            app.PharmaCheckBox = uicheckbox(app.SessiontypePanel);
            app.PharmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @PharmaCheckBoxValueChanged, true);
            app.PharmaCheckBox.Text = 'Pharma';
            app.PdCOCheckBox = uicheckbox(app.SessiontypePanel);
            app.PdCOCheckBox.ValueChangedFcn = createCallbackFcn(app, @PdCOCheckBoxValueChanged, true);
            app.PdCOCheckBox.Text = 'PdCO';
            app.WhiskerparametersPanel = uipanel(app.UIFigure);
            app.WhiskerparametersPanel.Title = 'Whisker parameters';
            app.DurationmsEditFieldLabel = uilabel(app.WhiskerparametersPanel);
            app.DurationmsEditFieldLabel.Text = 'Duration [ms]';
            app.WhStimDuration = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimDuration.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimDuration.Value = 3;
            app.ScalingfactorEditFieldLabel = uilabel(app.WhiskerparametersPanel);
            app.ScalingfactorEditFieldLabel.Text = 'Scaling factor';
            app.WhStimScalingFactor = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimScalingFactor.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimScalingFactor.Value = 0.9;
            app.WhStimAmpRange = uicheckbox(app.WhiskerparametersPanel);
            app.WhStimAmpRange.ValueChangedFcn = createCallbackFcn(app, @WhStimAmpRangeValueChanged, true);
            app.WhStimAmpRange.Text = 'Amplitude range';
            app.AmplitudeVEditFieldLabel = uilabel(app.WhiskerparametersPanel);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude [V]';
            app.WhStimAmp_1 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_1.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_1.Value = 3;
            app.AmplitudemTEditFieldLabel = uilabel(app.WhiskerparametersPanel);
            app.AmplitudemTEditFieldLabel.Text = 'Amplitude [mT]';
            app.WhStimAmp_1mT = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_1mT.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_1mT.Value = 30;
            app.WhweightEditFieldLabel = uilabel(app.WhiskerparametersPanel);
            app.WhweightEditFieldLabel.Text = 'Wh. weight';
            app.WhStimWeight_1 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimWeight_1.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimWeight_1.Value = 10;
            app.WhStimAmp_2 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_2.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_3 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_3.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_4 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_4.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_5 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_5.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_2mT = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_2mT.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimWeight_2 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimWeight_2.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_3mT = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_3mT.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimWeight_3 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimWeight_3.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_4mT = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_4mT.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimWeight_4 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimWeight_4.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimAmp_5mT = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimAmp_5mT.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.WhStimWeight_5 = uieditfield(app.WhiskerparametersPanel, 'numeric');
            app.WhStimWeight_5.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.AuditoryparametersPanel = uipanel(app.UIFigure);
            app.AuditoryparametersPanel.Title = 'Auditory parameters';
            app.DurationmsEditField_2Label = uilabel(app.AuditoryparametersPanel);
            app.DurationmsEditField_2Label.Text = 'Duration [ms]';
            app.AudStimDuration = uieditfield(app.AuditoryparametersPanel, 'numeric');
            app.AudStimDuration.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.AudStimDuration.Value = 10;
            app.AmplitudeVEditFieldLabel_2 = uilabel(app.AuditoryparametersPanel);
            app.AmplitudeVEditFieldLabel_2.Text = 'Amplitude [V]';
            app.AudStimAmplitude = uieditfield(app.AuditoryparametersPanel, 'numeric');
            app.AudStimAmplitude.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.AudStimAmplitude.Value = 5;
            app.FrequencyHzEditFieldLabel = uilabel(app.AuditoryparametersPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.AudStimFrequency = uieditfield(app.AuditoryparametersPanel, 'numeric');
            app.AudStimFrequency.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.AudStimFrequency.Value = 10000;
            app.AudweightEditFieldLabel = uilabel(app.AuditoryparametersPanel);
            app.AudweightEditFieldLabel.Text = 'Aud. weight';
            app.AudStimWeight = uieditfield(app.AuditoryparametersPanel, 'numeric');
            app.AudStimWeight.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.BackgroundnoisedirectoryEditFieldLabel = uilabel(app.AuditoryparametersPanel);
            app.BackgroundnoisedirectoryEditFieldLabel.Text = 'Background noise directory';
            app.BackgroundNoiseDirectory = uieditfield(app.AuditoryparametersPanel, 'text');
            app.BackgroundNoiseDirectory.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.BackgroundNoiseDirectory.Enable = 'off';
            app.ContextSoundON = uicheckbox(app.AuditoryparametersPanel);
            app.ContextSoundON.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.ContextSoundON.Enable = 'off';
            app.ContextSoundON.Text = 'Context sound ON';
            app.ContextSoundON.Value = true;
            app.LightparametersPanel = uipanel(app.UIFigure);
            app.LightparametersPanel.Title = 'Light parameters';
            app.DurationmsEditField_3Label = uilabel(app.LightparametersPanel);
            app.DurationmsEditField_3Label.Text = 'Duration [ms]';
            app.LightStimDuration = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimDuration.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimDuration.Value = 10;
            app.AmplitudeVEditField_2Label = uilabel(app.LightparametersPanel);
            app.AmplitudeVEditField_2Label.Text = 'Amplitude [V]';
            app.LightStimAmp_1 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimAmp_1.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimAmp_1.Value = 5;
            app.LightStimAmp_2 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimAmp_2.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimAmp_3 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimAmp_3.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimAmp_4 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimAmp_4.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimAmp_5 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimAmp_5.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimAmpRange = uicheckbox(app.LightparametersPanel);
            app.LightStimAmpRange.ValueChangedFcn = createCallbackFcn(app, @LightStimAmpRangeValueChanged, true);
            app.LightStimAmpRange.Text = 'Amplitude range';
            app.PowermWEditFieldLabel = uilabel(app.LightparametersPanel);
            app.PowermWEditFieldLabel.Text = 'Power [mW]';
            app.LightStimPower_1 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimPower_1.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimPower_1.Value = 10;
            app.LightStimPower_2 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimPower_2.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimPower_3 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimPower_3.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimPower_4 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimPower_4.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimPower_5 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimPower_5.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightweightEditFieldLabel = uilabel(app.LightparametersPanel);
            app.LightweightEditFieldLabel.Text = 'Light weight';
            app.LightStimWeight_1 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimWeight_1.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimWeight_2 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimWeight_2.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimWeight_3 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimWeight_3.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimWeight_4 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimWeight_4.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.LightStimWeight_5 = uieditfield(app.LightparametersPanel, 'numeric');
            app.LightStimWeight_5.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            app.NostimulusparametersPanel = uipanel(app.UIFigure);
            app.NostimulusparametersPanel.Title = 'No stimulus parameters';
            app.NostimulusweightEditFieldLabel = uilabel(app.NostimulusparametersPanel);
            app.NostimulusweightEditFieldLabel.Text = 'No stimulus weight';
            app.NostimulusweightEditField = uieditfield(app.NostimulusparametersPanel, 'numeric');
            app.NostimulusweightEditField.ValueChangedFcn = createCallbackFcn(app, @genericFieldChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Detection_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end